classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClassificatoreButton          matlab.ui.control.Button
        SelezionarumoreDropDownLabel  matlab.ui.control.Label
        SelezionarumoreDropDown       matlab.ui.control.DropDown
        EtichettapredettaEditFieldLabel  matlab.ui.control.Label
        EtichettapredettaEditField    matlab.ui.control.EditField
        EtichettaveraEditFieldLabel   matlab.ui.control.Label
        EtichettaveraEditField        matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: ClassificatoreButton
        function classificatore(app, event)
imagespath=dir('C:\Users\Simone\Desktop\uni\immagini\database per gli elaborati di Tipo 2-20190208\Yale\Yale');
imagespath;
ImagesPath=imagespath(4:end,:);% Sono eliminati i primi tre elementi perche' non sono immagini

% creiamo una struct images in cui inserire solo le immagini(unit8) e le tipologie(char)/classi
images=struct;
for i=1:size(ImagesPath,1)
    images(i).images=imread([ImagesPath(i).folder,'/',ImagesPath(i).name]);
    images(i).tipologia = extractAfter(ImagesPath(i).name,".");
end
        
              rum = getappdata(0,'rumore');
           features(1).features = extractHOGFeatures((im2double(images(get(app.EditField,'Value')).images)+sqrt(rum(1))*randn(size(images(get(app.EditField,'Value')).images))), ...
               'CellSize',[2 2]);
         
          v = evalin('base', 'classifier');
          test_feature =  features(1).features;
          predizioni= predict(v,  test_feature)
          
           switch predizioni
        case 1
           string1 = 'glasses'; 
        case 2
            string1 = 'happy';
        case 3
            string1 = 'leftlight';
        case 4
            string1 = 'noglasses';
        case 5
            string1 = 'normal';
        case 6
            string1 = 'rightlight';
        case 7
            string1 = 'sad';
        case 8
            string1 = 'sleepy';
        case 9
            string1 = 'surprised';
        case 10
            string1 = 'wink';
        case 11
            string1 = 'centerlight';                
    end
          
          
          set(app.EtichettapredettaEditField, 'Value', string1);
          set(app.EtichettaveraEditField, 'Value', images(get(app.EditField,'Value')).tipologia);
          imshow(images(get(app.EditField,'Value')).images);
    
        end

        % Value changed function: SelezionarumoreDropDown
        function selezione_rumore(app, event)
            value = app.SelezionarumoreDropDown.Value;
            setappdata(0,'rumore',str2num(value));
           
        end

        % Callback function
        function selezione_cell_size(app, event)
            cell_size = app.SelezionacellsizeDropDown.Value;
            setappdata(0,'cell_size',str2num(cell_size));
            
        end

        % Value changing function: EtichettapredettaEditField
        function uscita(app, event)
        
        end

        % Callback function
        function database(app, event)


        end

        % Value changed function: EditField
        function valore(app, event)
            value = app.EditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClassificatoreButton = uibutton(app.UIFigure, 'push');
            app.ClassificatoreButton.ButtonPushedFcn = createCallbackFcn(app, @classificatore, true);
            app.ClassificatoreButton.Text = 'Classificatore';
            app.SelezionarumoreDropDownLabel = uilabel(app.UIFigure);
            app.SelezionarumoreDropDownLabel.Text = 'Seleziona rumore';
            app.SelezionarumoreDropDown = uidropdown(app.UIFigure);
            app.SelezionarumoreDropDown.Items = {'0', '0.0001', '0.001', '0.01', '0.1'};
            app.SelezionarumoreDropDown.ValueChangedFcn = createCallbackFcn(app, @selezione_rumore, true);
            app.SelezionarumoreDropDown.Value = '0';
            app.EtichettapredettaEditFieldLabel = uilabel(app.UIFigure);
            app.EtichettapredettaEditFieldLabel.Text = 'Etichetta predetta';
            app.EtichettapredettaEditField = uieditfield(app.UIFigure, 'text');
            app.EtichettapredettaEditField.ValueChangingFcn = createCallbackFcn(app, @uscita, true);
            app.EtichettaveraEditFieldLabel = uilabel(app.UIFigure);
            app.EtichettaveraEditFieldLabel.Text = 'Etichetta vera';
            app.EtichettaveraEditField = uieditfield(app.UIFigure, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @valore, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end